classdef ProjektSynchrofazory < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        AkcjeMenu           matlab.ui.container.Menu
        DodajurzdzenieMenu  matlab.ui.container.Menu
        UsuurzdzenieMenu    matlab.ui.container.Menu
        MapaMenu            matlab.ui.container.Menu
        UstawieniaMenu      matlab.ui.container.Menu
        GridForPlots        matlab.ui.container.GridLayout
        Panel               matlab.ui.container.Panel
        GridLayout2         matlab.ui.container.GridLayout
        NastpnyButton       matlab.ui.control.Button
        PoprzedniButton     matlab.ui.control.Button
        PageNumber          matlab.ui.control.Label
    end

    
    properties (Access = public)
        DataQueueRecv; % Property used to get data from function running on other thread
        DataQueueSend; % Property used to put data to function running on other thread
        Pool;  % Pool of threads
        Task; 
        NumberOfPlotsPerPage=4; % Ilosc wykresów na jednej stronie
        ColorOfPlots=[0.00 0.45 0.74]; % Kolor wykresów
        IpList=["192.168.22.103"];
        Port=4713;
        ListOfPMU=[]

    end
    
    properties (Access = private)
        OptionsDialog % Okno dialogowe dla zmiany ustawień
    end
   
    methods (Access = public,Static)
          
        function ReciveUDP(queueOut,queueIn,IpList,Port)%function used to open and recive data datagram from UDP port
            connection = udpport("datagram","Ipv4","LocalPort",Port,"LocalHost",IpList,"OutputDatagramSize",96,"EnablePortSharing",true);
            while true
                if queueIn.QueueLength>0
                    break;
                elseif connection.DatagramsAvailableFcnCount>0
                    send(queueOut,read(connection,1,"uint8"));
                end
            end
            delete(connection);
        end

    end
    methods (Access = public)
        function DataQueueListener(app,data)

           
           tempPMU=PMU();
           tempPMU.InsertFrame(data);
        end
        
        function UpdateOptions(app)
            
        end
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Pool = parpool('local', 2);
            app.DataQueueRecv=parallel.pool.DataQueue;
            app.DataQueueSend=parallel.pool.DataQueue;
            afterEach(app.DataQueueRecv,@(data) DataQueueListener(app,data));
            parfeval(app.Pool,@UDPconne,0,app.DataQueueRecv,app.DataQueueSend,app.IpList,app.Port);
        end

        % Menu selected function: MapaMenu
        function MapaMenuSelected(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            send(app.DataQueueSend,"Die");
            delete(gcp("nocreate"));
            delete(app.OptionsDialog);
            delete(app)
        end

        % Menu selected function: UstawieniaMenu
        function UstawieniaMenuSelected(app, event)
            app.OptionsDialog = UstawieniaDialog(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AkcjeMenu = uimenu(app.UIFigure);
            app.AkcjeMenu.Text = 'Akcje';
            app.DodajurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.DodajurzdzenieMenu.Text = 'Dodaj urządzenie';
            app.UsuurzdzenieMenu = uimenu(app.AkcjeMenu);
            app.UsuurzdzenieMenu.Text = 'Usuń urządzenie';
            app.MapaMenu = uimenu(app.UIFigure);
            app.MapaMenu.MenuSelectedFcn = createCallbackFcn(app, @MapaMenuSelected, true);
            app.MapaMenu.Text = 'Mapa';
            app.UstawieniaMenu = uimenu(app.UIFigure);
            app.UstawieniaMenu.MenuSelectedFcn = createCallbackFcn(app, @UstawieniaMenuSelected, true);
            app.UstawieniaMenu.Text = 'Ustawienia';
            app.GridForPlots = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridForPlots);
            app.GridLayout2 = uigridlayout(app.Panel);
            app.PageNumber = uilabel(app.GridLayout2);
            app.PageNumber.Tag = 'PageNumber';
            app.PageNumber.Text = {'1'; ''};
            app.PoprzedniButton = uibutton(app.GridLayout2, 'push');
            app.PoprzedniButton.Text = {'Poprzedni'; ''};
            app.NastpnyButton = uibutton(app.GridLayout2, 'push');
            app.NastpnyButton.Text = 'Następny';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjektSynchrofazory

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end